classdef DOA_methods < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Target2angleEditField       matlab.ui.control.NumericEditField
        Target2angleEditFieldLabel  matlab.ui.control.Label
        Target1angleEditField       matlab.ui.control.NumericEditField
        Target1angleEditFieldLabel  matlab.ui.control.Label
        SnapshotSlider              matlab.ui.control.Slider
        SnapshotSliderLabel         matlab.ui.control.Label
        SNRSlider                   matlab.ui.control.Slider
        SNRSliderLabel              matlab.ui.control.Label
        ArrayNumSlider              matlab.ui.control.Slider
        ArrayNumSliderLabel         matlab.ui.control.Label
        GenerateButton              matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        
        function [doa_cbf,doa_capon,doa_music,angleIndex] = myDOA(app,arrayNum,snapshotLength,SNR,ang1,ang2)
%Parameters:
c=3e8;%光速
fc=1.5e9;    %雷达载频
lambda=c/fc; %波长
d=lambda/2;  %阵元间距
theta_T=[ang1,ang2].'; %目标来向角
targetNum=length(theta_T);   %目标个数
%arrayNum=16;                 %阵元个数
arrayPos = 0:d:(arrayNum-1) * d; %阵元位置
sampleRate= 48e5;            %采样率
ts = 1/sampleRate;           %采样间隔
L = snapshotLength;          %快拍数

angleIndex = asin((-512:1:512-1)/512) * 180 /pi; %角度纲量
angleIndexLength = length(angleIndex);
timeIndex = (0:1:L-1)*ts;                        %时间纲量

%Signal Genration

As = zeros(arrayNum,targetNum);  % A(theta)
S_signal = zeros(targetNum,L);   % s(n)
%分别定义A(theta) 与 s(n)
for i=1:1:targetNum
    As(:,i) = exp(-1j*(2*pi/lambda).*arrayPos.'*(sind(theta_T(i))));
    S_signal(i,:) = exp(1j*2*pi*fc*timeIndex);
    fc=fc+1000;  %假设后一个目标回波信号的频率高1000Hz
end

S_signal = As * S_signal;      % X(n) = A(theta) * S(n)
%SNR = 20;                      % 设定信噪比
S_signal = awgn(S_signal,SNR); % 添加高斯白噪声 即x(n) = x(n) + N(n)

%定义权矢量矩阵 W
W = zeros(arrayNum,angleIndexLength);
for i=1:angleIndexLength
    W(:,i)=exp(-1j*(2*pi/lambda).*arrayPos.'*sind(angleIndex(i)));
end

%信号协方差矩阵的最大似然估计（标准化后的自相关矩阵）
R_hat = (S_signal * conj(S_signal).' ) / L;
R_hatInv = inv(R_hat);
%DOA Algorithms

%CBF方法
doa_cbf=zeros(angleIndexLength,1);

%P(w)=W^H R W
for i=1:1:angleIndexLength
    doa_cbf(i)=conj(W(:,i).') * R_hat * W(:,i);
end


%Capon方法
doa_capon = zeros(angleIndexLength,1);

%P(w) = 1 / A^H R^-1 
for i=1:1:angleIndexLength
    doa_capon(i)= 1 / (conj(W(:,i).')* R_hatInv * W(:,i));
end

%MUSIC方法
doa_music = zeros(angleIndexLength,1);
[EV,D] = eig(R_hat);    %将协方差矩阵进行特征分解
EVA = diag(D)';         %提取特征值矩阵对角线，并转为一行
[EVA,I] = sort(EVA);    %将特征值从小到大排序
EV = fliplr(EV(:,I));   %对应特征矢量的排序
Un = EV(:,targetNum+1:arrayNum);
%P(w) = 1/ A*Un*Un^H*A
for i = 1:1:length(angleIndex)
    doa_music(i)=1/(conj(W(:,i).') * Un * Un' * W(:,i));
end

end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: GenerateButton
        function buttonPushed(app, event)

    % 获取滑块的值
    
    arrayNum = app.ArrayNumSlider.Value;
    snapshotLength = app.SnapshotSlider.Value;
    SNR = app.SNRSlider.Value;
    a1 = app.Target1angleEditField.Value;
    a2 = app.Target2angleEditField.Value;
    
    cla(app.UIAxes);
    % 调用DOA函数
    [doa_cbf, doa_capon, doa_music, angleIndex] = myDOA(app,arrayNum, snapshotLength, SNR,a1,a2);
    
    % 在坐标区中绘制结果
    plot(app.UIAxes, angleIndex, db(doa_cbf/max(doa_cbf)),"Color",'b',LineWidth=1.5,DisplayName='CBF');
    hold(app.UIAxes, 'on'); 
    plot(app.UIAxes, angleIndex, db(doa_capon/max(doa_capon)),"Color",'r',LineWidth=1.5,DisplayName='Capon');
    hold(app.UIAxes, 'on'); 
    plot(app.UIAxes, angleIndex, db(doa_music/max(doa_music)),"Color",'g',LineWidth=1.5,DisplayName='Music');
    xlabel(app.UIAxes, 'Angle/Degree');
    ylabel(app.UIAxes, 'dB');
    title(app.UIAxes, 'DOA结果');
    legend(app.UIAxes);
    grid(app.UIAxes, "on")

        end

        % Value changed function: ArrayNumSlider
        function ArrayNumSliderValueChanged(app, event)

            value = round(app.ArrayNumSlider.Value);
            app.ArrayNumSlider.Value = value;
        end

        % Value changed function: SNRSlider
        function SNRSliderValueChanged(app, event)
            value = round(app.SNRSlider.Value);
            app.SNRSlider.Value = value;
            
        end

        % Value changed function: SnapshotSlider
        function SnapshotSliderValueChanged(app, event)
            value = round(app.SnapshotSlider.Value);
            app.SnapshotSlider.Value = value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'DOA 结果')
            xlabel(app.UIAxes, 'Angle/Degree')
            ylabel(app.UIAxes, 'dB')
            zlabel(app.UIAxes, 'Z')
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @buttonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ArrayNumSliderLabel = uilabel(app.UIFigure);
            app.ArrayNumSliderLabel.Text = 'ArrayNum';
            app.ArrayNumSlider = uislider(app.UIFigure);
            app.ArrayNumSlider.Limits = [1 32];
            app.ArrayNumSlider.MajorTicks = [1 8 16 24 32];
            app.ArrayNumSlider.ValueChangedFcn = createCallbackFcn(app, @ArrayNumSliderValueChanged, true);
            app.ArrayNumSlider.MinorTicks = [];
            app.ArrayNumSlider.Value = 8;
            app.SNRSliderLabel = uilabel(app.UIFigure);
            app.SNRSliderLabel.Text = 'SNR';
            app.SNRSlider = uislider(app.UIFigure);
            app.SNRSlider.Limits = [5 30];
            app.SNRSlider.ValueChangedFcn = createCallbackFcn(app, @SNRSliderValueChanged, true);
            app.SNRSlider.Value = 20;
            app.SnapshotSliderLabel = uilabel(app.UIFigure);
            app.SnapshotSliderLabel.Text = 'Snapshot';
            app.SnapshotSlider = uislider(app.UIFigure);
            app.SnapshotSlider.Limits = [100 1000];
            app.SnapshotSlider.ValueChangedFcn = createCallbackFcn(app, @SnapshotSliderValueChanged, true);
            app.SnapshotSlider.Value = 500;
            app.Target1angleEditFieldLabel = uilabel(app.UIFigure);
            app.Target1angleEditFieldLabel.Text = 'Target 1 angle';
            app.Target1angleEditField = uieditfield(app.UIFigure, 'numeric');
            app.Target1angleEditField.Limits = [-90 90];
            app.Target1angleEditField.Value = 10;
            app.Target2angleEditFieldLabel = uilabel(app.UIFigure);
            app.Target2angleEditFieldLabel.Text = 'Target 2 angle';
            app.Target2angleEditField = uieditfield(app.UIFigure, 'numeric');
            app.Target2angleEditField.Limits = [-90 90];
            app.Target2angleEditField.Value = 20;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DOA_methods

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end